classdef systemModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        DummyMenu                    matlab.ui.container.Menu
        ShortcutsMenu                matlab.ui.container.Menu
        DroptobaseworkspaceMenu      matlab.ui.container.Menu
        UpdatesystemMenu             matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        SystemTab                    matlab.ui.container.Tab
        TButton                      matlab.ui.control.Button
        aButton                      matlab.ui.control.Button
        CouplingButton               matlab.ui.control.Button
        TXantennaButton              matlab.ui.control.Button
        DButton                      matlab.ui.control.Button
        DelaydButton                 matlab.ui.control.Button
        DelayRButton                 matlab.ui.control.Button
        DelayR0Button_2              matlab.ui.control.Button
        TagButton                    matlab.ui.control.Button
        CButton                      matlab.ui.control.Button
        BackgroundButton             matlab.ui.control.Button
        OButton                      matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        SourceButton                 matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        RXantennaButton              matlab.ui.control.Button
        DetectorButton               matlab.ui.control.Button
        bButton                      matlab.ui.control.Button
        D_dButton                    matlab.ui.control.Button
        T_RButton                    matlab.ui.control.Button
        T_R0Button                   matlab.ui.control.Button
        DelayRButton_2               matlab.ui.control.Button
        DelayR0Button                matlab.ui.control.Button
        C_RButton                    matlab.ui.control.Button
        O_R0Button                   matlab.ui.control.Button
        RButton                      matlab.ui.control.Button
        dEditFieldLabel              matlab.ui.control.Label
        dEditField                   matlab.ui.control.NumericEditField
        REditFieldLabel              matlab.ui.control.Label
        REditField                   matlab.ui.control.NumericEditField
        R0EditFieldLabel             matlab.ui.control.Label
        R0EditField                  matlab.ui.control.NumericEditField
        NoiseButton                  matlab.ui.control.Button
        NoiseButton_2                matlab.ui.control.Button
        UpdateButton                 matlab.ui.control.Button
        systemSideView               matlab.ui.control.UIAxes
        TagCheckBox1                 matlab.ui.control.CheckBox
        TagCheckBox2                 matlab.ui.control.CheckBox
        TagCheckBox3                 matlab.ui.control.CheckBox
        TagCheckBox4                 matlab.ui.control.CheckBox
        TagCheckBox5                 matlab.ui.control.CheckBox
        TagCheckBox6                 matlab.ui.control.CheckBox
        TagCheckBox7                 matlab.ui.control.CheckBox
        TagCheckBox8                 matlab.ui.control.CheckBox
        TxHeightEditFieldLabel       matlab.ui.control.Label
        TxHeightEditField            matlab.ui.control.NumericEditField
        RxHeightEditFieldLabel       matlab.ui.control.Label
        RxHeightEditField            matlab.ui.control.NumericEditField
        Label_3                      matlab.ui.control.Label
        ADCButton                    matlab.ui.control.Button
        digitizedButton              matlab.ui.control.Button
        samplerateEditFieldLabel     matlab.ui.control.Label
        samplerateEditField          matlab.ui.control.NumericEditField
        Plots                        matlab.ui.container.Panel
        impulseResponse              matlab.ui.control.UIAxes
        amplitudeResponse            matlab.ui.control.UIAxes
        phaseResponse                matlab.ui.control.UIAxes
        analogsimrateEditFieldLabel  matlab.ui.control.Label
        analogsimrateEditField       matlab.ui.control.NumericEditField
        scatterPointsEditFieldLabel  matlab.ui.control.Label
        scatterPointsEditField       matlab.ui.control.NumericEditField
        GroundDepthEditFieldLabel    matlab.ui.control.Label
        GroundDepthEditField         matlab.ui.control.NumericEditField
        SetupTab                     matlab.ui.container.Tab
        droptobaseworkspaceButton    matlab.ui.control.Button
        blockSetupTab                matlab.ui.container.Tab
        gainEditFieldLabel           matlab.ui.control.Label
        gainEditField                matlab.ui.control.NumericEditField
        systemBlockList              matlab.ui.container.TabGroup
        SourceTab                    matlab.ui.container.Tab
        TX_antennaTab                matlab.ui.container.Tab
        CouplingTab                  matlab.ui.container.Tab
        TagTab                       matlab.ui.container.Tab
        BackgroundTab                matlab.ui.container.Tab
        RX_antennaTab                matlab.ui.container.Tab
        gainEditField_2Label         matlab.ui.control.Label
        gainEditField_2              matlab.ui.control.NumericEditField
        SaveButton                   matlab.ui.control.Button
        DetectionTab                 matlab.ui.container.Tab
    end

    
    properties (Access = private)
        shift=0
        enabledTags

    end
    
    properties (Access = public)
        systemStruct
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.systemBlockList.Position=([30,120,1170,626]); 
            app.systemStruct=systeminit(app);
        end

        % Button pushed function: CButton, C_RButton, DButton, 
        % D_dButton, OButton, O_R0Button, RButton, TButton, 
        % T_R0Button, T_RButton, aButton, bButton
        function plotSignalButton(app, event)
            app.Plots.Title=event.Source.Text;
            assignin("base","event",app.Plots)
            plotSignal(app,app.Plots.Title,app.systemStruct.simSampleRate)
        end

        % Button pushed function: BackgroundButton, CouplingButton, 
        % DelayR0Button, DelayR0Button_2, DelayRButton, 
        % DelayRButton_2, DelaydButton, RXantennaButton, 
        % SourceButton, TXantennaButton, TagButton
        function systemBlockButtonPushed(app, event)
            block=strrep( event.Source.Text , ' ' , '_' );
            if app.shift==0
                plotBlockResponse(app,block,app.systemStruct.simSampleRate);
            else
                app.systemBlockList.SelectedTab=app.(strcat(block,'Tab'));
                app.TabGroup.SelectedTab=app.blockSetupTab;
                app.shift=0;
            end
            
        end

        % Window key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
             assignin("base","event",event)
            switch key
                case 'shift'
                    app.shift=1;
                case 'escape'
                    delete(app)
                case '1'
                    app.TabGroup.SelectedTab=app.SystemTab;
                case '2'
                    app.TabGroup.SelectedTab=app.SetupTab;
                case '3'
                    app.TabGroup.SelectedTab=app.blockSetupTab;
                case '4'
                    app.TabGroup.SelectedTab=app.DetectionTab;
            end
        end

        % Callback function: DroptobaseworkspaceMenu, 
        % droptobaseworkspaceButton
        function droptobaseworkspaceButtonPushed(app, event)
            assignin("base","troubleshoot",app.systemStruct)
        end

        % Menu selected function: DummyMenu
        function DummyMenuSelected(app, event)
            if strcmp(app.Menu_2.Checked,'off')
                app.Menu_2.Checked= 'on';
            else
                app.Menu_2.Checked='off';
            end
        end

        % Callback function
        function InputpulseDropDownValueChanged(app, event)
            value = app.InputpulseDropDown.Value;
            switch value
                case 'Impulse'
                    app.TabGroup2.SelectedTab=app.impulsesetupTab;
                case 'short pulse'
                    app.TabGroup2.SelectedTab=app.pulsesetupTab;
            end
        end

        % Callback function: SaveButton, SaveMenu
        function SaveButtonPushed(app, event)
            app.systemStruct.(app.systemBlockList.SelectedTab.Title).response=app.gainEditField.Value;
            %assignin('base','response',(app.systemBlockList.SelectedTab.Title))%=app.gainEditField.Value;
            app.TabGroup.SelectedTab=app.SystemTab;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.systemStruct=updateSystem(app,app.systemStruct);
            assignin('base','t',app.systemStruct.T);
        end

        % Window key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'shift'
                    app.shift=0;
            end
        end

        % Value changed function: TagCheckBox1, TagCheckBox2, 
        % TagCheckBox3, TagCheckBox4, TagCheckBox5, TagCheckBox6, 
        % TagCheckBox7, TagCheckBox8
        function SelectTaglocationButtonPushed(app, event)
            app.enabledTags(:, str2num(event.Source.Text) ) = event.Source.Value;
            if event.Source.Value    
                    app.systemStruct.geometry.Tags(:, str2num(event.Source.Text) )=SelectTaglocation(app.systemStruct,app.UIFigure.Position);
                
            else
                app.systemStruct.geometry.Tags(:, str2num(event.Source.Text) )=[0;-2*app.systemStruct.geometry.GroundDepth];   
            end
            plotSideView(app.systemSideView,app.systemStruct.geometry)
        end

        % Button pushed function: DetectorButton
        function DetectorButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.DetectionTab;
        end

        % Callback function
        function EnabledtagsButtonGroupSelectionChanged(app, event)
            selectedButton = app.EnabledtagsButtonGroup.SelectedObject;
            assignin('base','buttons',event.Source)
        end

        % Button pushed function: digitizedButton
        function digitizedButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.DetectionTab;
        end

        % Value changed function: scatterPointsEditField
        function scatterPointsEditFieldValueChanged(app, event)
            N = app.scatterPointsEditField.Value;
            app.systemStruct.geometry.scatterers.x=randInRange(N,-app.systemStruct.geometry.tempWidth,app.systemStruct.geometry.tempWidth);
            app.systemStruct.geometry.scatterers.y=randInRange(N,-app.systemStruct.geometry.GroundDepth,0);
            plotSideView(app.systemSideView,app.systemStruct.geometry)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.DummyMenu = uimenu(app.Menu);
            app.DummyMenu.MenuSelectedFcn = createCallbackFcn(app, @DummyMenuSelected, true);
            app.DummyMenu.Text = 'Dummy';
            app.ShortcutsMenu = uimenu(app.UIFigure);
            app.ShortcutsMenu.Text = 'Shortcuts';
            app.DroptobaseworkspaceMenu = uimenu(app.ShortcutsMenu);
            app.DroptobaseworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @droptobaseworkspaceButtonPushed, true);
            app.DroptobaseworkspaceMenu.Accelerator = 'd';
            app.DroptobaseworkspaceMenu.Text = 'Drop to base workspace';
            app.UpdatesystemMenu = uimenu(app.ShortcutsMenu);
            app.UpdatesystemMenu.Accelerator = 'u';
            app.UpdatesystemMenu.Text = 'Update system';
            app.SaveMenu = uimenu(app.ShortcutsMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.TButton = uibutton(app.SystemTab, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.TButton.Text = 'T';
            app.aButton = uibutton(app.SystemTab, 'push');
            app.aButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.aButton.Text = 'a';
            app.CouplingButton = uibutton(app.SystemTab, 'push');
            app.CouplingButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.CouplingButton.Text = 'Coupling';
            app.TXantennaButton = uibutton(app.SystemTab, 'push');
            app.TXantennaButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.TXantennaButton.Text = 'TX antenna';
            app.DButton = uibutton(app.SystemTab, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.DButton.Text = 'D';
            app.DelaydButton = uibutton(app.SystemTab, 'push');
            app.DelaydButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelaydButton.Text = 'Delay d';
            app.DelayRButton = uibutton(app.SystemTab, 'push');
            app.DelayRButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelayRButton.Text = 'Delay R';
            app.DelayR0Button_2 = uibutton(app.SystemTab, 'push');
            app.DelayR0Button_2.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelayR0Button_2.Text = 'Delay R0';
            app.TagButton = uibutton(app.SystemTab, 'push');
            app.TagButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.TagButton.Text = 'Tag';
            app.CButton = uibutton(app.SystemTab, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.CButton.Text = 'C';
            app.BackgroundButton = uibutton(app.SystemTab, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            app.OButton = uibutton(app.SystemTab, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.OButton.Text = 'O';
            app.Label = uilabel(app.SystemTab);
            app.Label.Text = {'------------------------------------------------------------------'; '            |                                   |                              |'};
            app.SourceButton = uibutton(app.SystemTab, 'push');
            app.SourceButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.SourceButton.Text = 'Source';
            app.Label_2 = uilabel(app.SystemTab);
            app.Label_2.Text = {'            |                                   |                              |'; '------------------------------------------------------------------'};
            app.RXantennaButton = uibutton(app.SystemTab, 'push');
            app.RXantennaButton.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.RXantennaButton.Text = 'RX antenna';
            app.DetectorButton = uibutton(app.SystemTab, 'push');
            app.DetectorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorButtonPushed, true);
            app.DetectorButton.Text = 'Detector';
            app.bButton = uibutton(app.SystemTab, 'push');
            app.bButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.bButton.Text = 'b';
            app.D_dButton = uibutton(app.SystemTab, 'push');
            app.D_dButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.D_dButton.Text = 'D_d';
            app.T_RButton = uibutton(app.SystemTab, 'push');
            app.T_RButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.T_RButton.Text = 'T_R';
            app.T_R0Button = uibutton(app.SystemTab, 'push');
            app.T_R0Button.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.T_R0Button.Text = 'T_R0';
            app.DelayRButton_2 = uibutton(app.SystemTab, 'push');
            app.DelayRButton_2.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelayRButton_2.Text = 'Delay R';
            app.DelayR0Button = uibutton(app.SystemTab, 'push');
            app.DelayR0Button.ButtonPushedFcn = createCallbackFcn(app, @systemBlockButtonPushed, true);
            app.DelayR0Button.Text = 'Delay R0';
            app.C_RButton = uibutton(app.SystemTab, 'push');
            app.C_RButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.C_RButton.Text = 'C_R';
            app.O_R0Button = uibutton(app.SystemTab, 'push');
            app.O_R0Button.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.O_R0Button.Text = 'O_R0';
            app.RButton = uibutton(app.SystemTab, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @plotSignalButton, true);
            app.RButton.Text = 'R';
            app.dEditFieldLabel = uilabel(app.SystemTab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.SystemTab, 'numeric');
            app.REditFieldLabel = uilabel(app.SystemTab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SystemTab, 'numeric');
            app.R0EditFieldLabel = uilabel(app.SystemTab);
            app.R0EditFieldLabel.Text = 'R0';
            app.R0EditField = uieditfield(app.SystemTab, 'numeric');
            app.NoiseButton = uibutton(app.SystemTab, 'push');
            app.NoiseButton.Text = 'Noise';
            app.NoiseButton_2 = uibutton(app.SystemTab, 'push');
            app.NoiseButton_2.Text = 'Noise';
            app.UpdateButton = uibutton(app.SystemTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.systemSideView = uiaxes(app.SystemTab);
            title(app.systemSideView, 'Title')
            xlabel(app.systemSideView, 'X')
            ylabel(app.systemSideView, 'Y')
            app.TagCheckBox1 = uicheckbox(app.SystemTab);
            app.TagCheckBox1.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox1.Text = '1';
            app.TagCheckBox2 = uicheckbox(app.SystemTab);
            app.TagCheckBox2.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox2.Text = '2';
            app.TagCheckBox3 = uicheckbox(app.SystemTab);
            app.TagCheckBox3.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox3.Text = '3';
            app.TagCheckBox4 = uicheckbox(app.SystemTab);
            app.TagCheckBox4.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox4.Text = '4';
            app.TagCheckBox5 = uicheckbox(app.SystemTab);
            app.TagCheckBox5.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox5.Text = '5';
            app.TagCheckBox6 = uicheckbox(app.SystemTab);
            app.TagCheckBox6.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox6.Text = '6';
            app.TagCheckBox7 = uicheckbox(app.SystemTab);
            app.TagCheckBox7.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox7.Text = '7';
            app.TagCheckBox8 = uicheckbox(app.SystemTab);
            app.TagCheckBox8.ValueChangedFcn = createCallbackFcn(app, @SelectTaglocationButtonPushed, true);
            app.TagCheckBox8.Text = '8';
            app.TxHeightEditFieldLabel = uilabel(app.SystemTab);
            app.TxHeightEditFieldLabel.Text = 'Tx Height';
            app.TxHeightEditField = uieditfield(app.SystemTab, 'numeric');
            app.RxHeightEditFieldLabel = uilabel(app.SystemTab);
            app.RxHeightEditFieldLabel.Text = 'Rx Height';
            app.RxHeightEditField = uieditfield(app.SystemTab, 'numeric');
            app.Label_3 = uilabel(app.SystemTab);
            app.ADCButton = uibutton(app.SystemTab, 'push');
            app.ADCButton.Text = 'ADC';
            app.digitizedButton = uibutton(app.SystemTab, 'push');
            app.digitizedButton.ButtonPushedFcn = createCallbackFcn(app, @digitizedButtonPushed, true);
            app.digitizedButton.Text = 'digitized';
            app.samplerateEditFieldLabel = uilabel(app.SystemTab);
            app.samplerateEditFieldLabel.Text = 'sample  rate';
            app.samplerateEditField = uieditfield(app.SystemTab, 'numeric');
            app.Plots = uipanel(app.SystemTab);
            app.Plots.Title = '[select a signal or system block to plot]';
            app.impulseResponse = uiaxes(app.Plots);
            title(app.impulseResponse, 'Impulse response')
            xlabel(app.impulseResponse, 'time (s)')
            ylabel(app.impulseResponse, 'Y')
            app.amplitudeResponse = uiaxes(app.Plots);
            title(app.amplitudeResponse, 'Frequency response (amplitude)')
            xlabel(app.amplitudeResponse, 'Frequency')
            ylabel(app.amplitudeResponse, '|Y|')
            app.phaseResponse = uiaxes(app.Plots);
            title(app.phaseResponse, 'Frequency response (angle)')
            xlabel(app.phaseResponse, 'Frequency')
            ylabel(app.phaseResponse, '\angleY')
            app.analogsimrateEditFieldLabel = uilabel(app.SystemTab);
            app.analogsimrateEditFieldLabel.Text = 'analog sim rate';
            app.analogsimrateEditField = uieditfield(app.SystemTab, 'numeric');
            app.scatterPointsEditFieldLabel = uilabel(app.SystemTab);
            app.scatterPointsEditFieldLabel.Text = 'scatterPoints';
            app.scatterPointsEditField = uieditfield(app.SystemTab, 'numeric');
            app.scatterPointsEditField.ValueChangedFcn = createCallbackFcn(app, @scatterPointsEditFieldValueChanged, true);
            app.GroundDepthEditFieldLabel = uilabel(app.SystemTab);
            app.GroundDepthEditFieldLabel.Text = 'GroundDepth';
            app.GroundDepthEditField = uieditfield(app.SystemTab, 'numeric');
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.droptobaseworkspaceButton = uibutton(app.SetupTab, 'push');
            app.droptobaseworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @droptobaseworkspaceButtonPushed, true);
            app.droptobaseworkspaceButton.Text = 'drop to base workspace';
            app.blockSetupTab = uitab(app.TabGroup);
            app.blockSetupTab.Title = 'block setup';
            app.gainEditFieldLabel = uilabel(app.blockSetupTab);
            app.gainEditFieldLabel.Text = 'gain';
            app.gainEditField = uieditfield(app.blockSetupTab, 'numeric');
            app.systemBlockList = uitabgroup(app.blockSetupTab);
            app.SourceTab = uitab(app.systemBlockList);
            app.SourceTab.Title = 'Source';
            app.TX_antennaTab = uitab(app.systemBlockList);
            app.TX_antennaTab.Title = 'TX_antenna';
            app.CouplingTab = uitab(app.systemBlockList);
            app.CouplingTab.Title = 'Coupling';
            app.TagTab = uitab(app.systemBlockList);
            app.TagTab.Title = 'Tag';
            app.BackgroundTab = uitab(app.systemBlockList);
            app.BackgroundTab.Title = 'Background';
            app.RX_antennaTab = uitab(app.systemBlockList);
            app.RX_antennaTab.Title = 'RX_antenna';
            app.gainEditField_2Label = uilabel(app.RX_antennaTab);
            app.gainEditField_2Label.Text = 'gain';
            app.gainEditField_2 = uieditfield(app.RX_antennaTab, 'numeric');
            app.SaveButton = uibutton(app.blockSetupTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = systemModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end